classdef Weir_Designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WeirDesignUIFigure             matlab.ui.Figure
        RunButton                      matlab.ui.control.Button
        WeirTypeandDecisionVaraiblesPanel  matlab.ui.container.Panel
        WeirTypeDropDownLabel          matlab.ui.control.Label
        WeirTypeDropDown               matlab.ui.control.DropDown
        DecisionVariablesPanel         matlab.ui.container.Panel
        LambdaLabel_2                  matlab.ui.control.Label
        CLabel_2                       matlab.ui.control.Label
        aLabel_2                       matlab.ui.control.Label
        CLabel_4                       matlab.ui.control.Label
        SLabel                         matlab.ui.control.Label
        CLabel_5                       matlab.ui.control.Label
        LLabel                         matlab.ui.control.Label
        CLabel_6                       matlab.ui.control.Label
        TETALabel                      matlab.ui.control.Label
        ModelInputsPanel               matlab.ui.container.Panel
        AreaKm2EditFieldLabel          matlab.ui.control.Label
        AreaKm2EditField               matlab.ui.control.NumericEditField
        dtsecEditFieldLabel            matlab.ui.control.Label
        dtsecEditField                 matlab.ui.control.NumericEditField
        gm2secEditFieldLabel           matlab.ui.control.Label
        gm2secEditField                matlab.ui.control.NumericEditField
        alphaEditFieldLabel            matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.NumericEditField
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        OptimizationInputsPanel        matlab.ui.container.Panel
        LowerBoundLabel                matlab.ui.control.Label
        UpperBoundLabel                matlab.ui.control.Label
        LambdaEditFieldLabel           matlab.ui.control.Label
        LambdaEditField                matlab.ui.control.NumericEditField
        LambdaEditField_2              matlab.ui.control.NumericEditField
        CEditFieldLabel                matlab.ui.control.Label
        CEditField                     matlab.ui.control.NumericEditField
        CEditField_2                   matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aEditField_2                   matlab.ui.control.NumericEditField
        WeirParametersLabel            matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        PopulationMultiplierEditFieldLabel  matlab.ui.control.Label
        PopulationMultiplierEditField  matlab.ui.control.NumericEditField
        GenerationsEditFieldLabel      matlab.ui.control.Label
        GenerationsEditField           matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        StatusPanel                    matlab.ui.container.Panel
        InitializingLabel              matlab.ui.control.Label
        RunningLabel                   matlab.ui.control.Label
        OPTIMIZATIONCOMPELETELabel     matlab.ui.control.Label
        ResultsPanel                   matlab.ui.container.Panel
        EditField                      matlab.ui.control.EditField
        CVaRHLabel                     matlab.ui.control.Label
        EditField_2                    matlab.ui.control.EditField
        CVaRQLabel                     matlab.ui.control.Label
        EditField_3                    matlab.ui.control.EditField
        CVaRDLabel                     matlab.ui.control.Label
        EditField_4                    matlab.ui.control.EditField
        meanHLabel_2                   matlab.ui.control.Label
        EditField_6                    matlab.ui.control.EditField
        meanQLabel_2                   matlab.ui.control.Label
        EditField_7                    matlab.ui.control.EditField
        RadQLabel_2                    matlab.ui.control.Label
        EditField_8                    matlab.ui.control.EditField
        meanDLabel_2                   matlab.ui.control.Label
        EditField_9                    matlab.ui.control.EditField
        RadDLabel_2                    matlab.ui.control.Label
        EditField_10                   matlab.ui.control.EditField
        LambdaLabel                    matlab.ui.control.Label
        EditField_5                    matlab.ui.control.EditField
        RadHLabel_2                    matlab.ui.control.Label
        ParetoDropDownLabel            matlab.ui.control.Label
        ParetoDropDown                 matlab.ui.control.DropDown
        EditField_11                   matlab.ui.control.EditField
        CLabel                         matlab.ui.control.Label
        EditField_12                   matlab.ui.control.EditField
        aLabel                         matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        RiskMethodsButtonGroup         matlab.ui.container.ButtonGroup
        CVaRButton                     matlab.ui.control.ToggleButton
        NINPButton                     matlab.ui.control.ToggleButton
        CVaRNINPButton                 matlab.ui.control.ToggleButton
        ObjectiveFunctionsPanel        matlab.ui.container.Panel
        MinimizeConditionalValueatRiskforLabel  matlab.ui.control.Label
        HeadCVaRHLabel                 matlab.ui.control.Label
        OutflowCVaRQLabel              matlab.ui.control.Label
        DeficitCVaRDLabel              matlab.ui.control.Label
        MinimizeLabel                  matlab.ui.control.Label
        LambdaLabel_3                  matlab.ui.control.Label
        CLabel_7                       matlab.ui.control.Label
        aLabel_3                       matlab.ui.control.Label
        CLabel_9                       matlab.ui.control.Label
        SLabel_2                       matlab.ui.control.Label
        CLabel_10                      matlab.ui.control.Label
        LLabel_2                       matlab.ui.control.Label
        CLabel_11                      matlab.ui.control.Label
        TETALabel_2                    matlab.ui.control.Label
        MinimizeConditionalValueatRiskforLabel_2  matlab.ui.control.Label
        HeadCVaRHLabel_2               matlab.ui.control.Label
        OutflowCVaRQLabel_2            matlab.ui.control.Label
        DeficitCVaRDLabel_2            matlab.ui.control.Label
        MinimizeLabel_2                matlab.ui.control.Label
        RadiusofHeadRadHLabel          matlab.ui.control.Label
        MinimizeLabel_3                matlab.ui.control.Label
        LambdaLabel_4                  matlab.ui.control.Label
        CLabel_8                       matlab.ui.control.Label
        aLabel_4                       matlab.ui.control.Label
        CLabel_12                      matlab.ui.control.Label
        SLabel_3                       matlab.ui.control.Label
        CLabel_13                      matlab.ui.control.Label
        CLabel_14                      matlab.ui.control.Label
        LLabel_3                       matlab.ui.control.Label
        TETALabel_3                    matlab.ui.control.Label
        MinimizeLabel_4                matlab.ui.control.Label
        RadiusofHeadRadHLabel_2        matlab.ui.control.Label
        MeanofHmeanHLabel_2            matlab.ui.control.Label
        RadiusofOutflowRadQLabel_2     matlab.ui.control.Label
        MeanofOutflowmeanQLabel_2      matlab.ui.control.Label
        RadiusofDeficitRadDLabel_2     matlab.ui.control.Label
        MeanofDeficitmeanDLabel_2      matlab.ui.control.Label
        MinimizeLabel_5                matlab.ui.control.Label
        LambdaLabel_5                  matlab.ui.control.Label
        CLabel_15                      matlab.ui.control.Label
        aLabel_5                       matlab.ui.control.Label
        CLabel_16                      matlab.ui.control.Label
        SLabel_4                       matlab.ui.control.Label
        LLabel_4                       matlab.ui.control.Label
        TETALabel_4                    matlab.ui.control.Label
        CLabel_17                      matlab.ui.control.Label
        CLabel_18                      matlab.ui.control.Label
        MeanofHmeanHLabel              matlab.ui.control.Label
        RadiusofOutflowRadQLabel       matlab.ui.control.Label
        MeanofOutflowmeanQLabel        matlab.ui.control.Label
        RadiusofDeficitRadDLabel       matlab.ui.control.Label
        MeanofDeficitmeanDLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        code
        cvnp
        nvars
        area
        grav
        deltat
        popmulti
        generation
        alpha
        lb1
        lb2
        lb3
        ub1
        ub2
        ub3
        lb
        ub
        im1
        im2
        im3
        im4
        y
        lambda_val
        s_val
        C_val
        l_val
        a_val
        tet_val
        Hm
        Qm
        DEF
        val
        H
        Q
        Def
        hmin
        hmax
        qmin
        qmax
        dmin
        dmax
        H1
        Q1
        Def1
        mm
        namem
        nnn
        namew
        HH
        QQ
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath([pwd '\Optimization+Allocation\'])
            app.UIAxes2.XLabel.String='Time (sec)';
            app.UIAxes2.YLabel.String='Head (m)';
            app.UIAxes2.Title.String='Water Profile';
            box(app.UIAxes2,'on');
            
            
            app.UIAxes3.XLabel.String='Time (sec)';
            app.UIAxes3.YLabel.String='Q (m^3/sec)';
            app.UIAxes3.Title.String='Outflow';
            box(app.UIAxes3,'on');
            
            app.InitializingLabel.Visible='on';
            app.RunningLabel.Visible='off';
            app.OPTIMIZATIONCOMPELETELabel.Visible='off';
            
            app.LambdaLabel_2.Visible='off';
            app.CLabel_2.Visible='off';
            app.aLabel_2.Visible='off';
            
            app.CLabel_4.Visible='off';
            app.SLabel.Visible='off';
            
            app.CLabel_5.Visible='off';
            app.LLabel.Visible='off';
            
            app.CLabel_6.Visible='off';
            app.TETALabel.Visible='off';
            
            % Objective Funcions of CVaR
            app.MinimizeConditionalValueatRiskforLabel.Visible='off';
            app.HeadCVaRHLabel.Visible='off';
            app.OutflowCVaRQLabel.Visible='off';
            app.DeficitCVaRDLabel.Visible='off';
            app.MinimizeLabel.Visible='off';
            % orifice
            app.LambdaLabel_3.Visible='off';
            app.CLabel_7.Visible='off';
            app.aLabel_3.Visible='off';
            % Proportional
            app.CLabel_9.Visible='off';
            app.SLabel_2.Visible='off';
            app.aLabel_3.Visible='off';
            % Rectangular
            app.CLabel_10.Visible='off';
            app.LLabel_2.Visible='off';
            app.aLabel_3.Visible='off';
            % Triangular
            app.CLabel_11.Visible='off';
            app.TETALabel_2.Visible='off';
            app.aLabel_3.Visible='off';
            
            % Objective Functions of NINP
            app.MinimizeLabel_2.Visible='off';
            app.RadiusofHeadRadHLabel.Visible='off';
            app.MeanofHmeanHLabel.Visible='off';
            app.RadiusofOutflowRadQLabel.Visible='off';
            app.MeanofOutflowmeanQLabel.Visible='off';
            app.RadiusofDeficitRadDLabel.Visible='off';
            app.MeanofDeficitmeanDLabel.Visible='off';
            app.MinimizeLabel_3.Visible='off';
            % Orifice
            app.LambdaLabel_4.Visible='off';
            app.CLabel_8.Visible='off';
            app.aLabel_4.Visible='off';
            % Proportional
            app.CLabel_12.Visible='off';
            app.SLabel_3.Visible='off';
            % Rectangular
            app.CLabel_13.Visible='off';
            app.LLabel_3.Visible='off';
            % Triangular
            app.CLabel_14.Visible='off';
            app.TETALabel_3.Visible='off';
            
            %Objective Functions of CVaR+NINP
            app.MinimizeConditionalValueatRiskforLabel_2.Visible='off';
            app.HeadCVaRHLabel_2.Visible='off';
            app.OutflowCVaRQLabel_2.Visible='off';
            app.DeficitCVaRDLabel_2.Visible='off';
            app.MinimizeLabel_4.Visible='off';
            app.RadiusofHeadRadHLabel_2.Visible='off';
            app.MeanofHmeanHLabel_2.Visible='off';
            app.RadiusofOutflowRadQLabel_2.Visible='off';
            app.MeanofOutflowmeanQLabel_2.Visible='off';
            app.RadiusofDeficitRadDLabel_2.Visible='off';
            app.MeanofDeficitmeanDLabel_2.Visible='off';
            app.MinimizeLabel_5.Visible='off';
            % Orifice
            app.LambdaLabel_5.Visible='off';
            app.CLabel_15.Visible='off';
            app.aLabel_5.Visible='off';
            % Propotional
            app.CLabel_16.Visible='off';
            app.SLabel_4.Visible='off';
            % Rectangular
            app.CLabel_17.Visible='off';
            app.LLabel_4.Visible='off';
            % Triangular
            app.CLabel_18.Visible='off';
            app.TETALabel_4.Visible='off';


        end

        % Value changed function: WeirTypeDropDown
        function WeirTypeDropDownValueChanged(app, event)
            app.RunningLabel.Visible='off';
            app.OPTIMIZATIONCOMPELETELabel.Visible='off';
            value = app.WeirTypeDropDown.Value;
            if strcmp(value,' ')
                app.EditField.Visible='off';
                app.EditField_2.Visible='off';
                app.EditField_3.Visible='off';
                app.EditField_4.Visible='off';
                app.EditField_5.Visible='off';
                app.EditField_6.Visible='off';
                app.EditField_7.Visible='off';
                app.EditField_8.Visible='off';
                app.EditField_9.Visible='off';
                app.EditField_10.Visible='off';
                app.EditField_11.Visible='off';
                app.EditField_12.Visible='off';
                app.CVaRHLabel.Visible='off';
                app.CVaRQLabel.Visible='off';
                app.CVaRDLabel.Visible='off';
                app.meanHLabel_2.Visible='off';
                app.RadHLabel_2.Visible='off';
                app.meanQLabel_2.Visible='off';
                app.RadQLabel_2.Visible='off';
                app.meanDLabel_2.Visible='off';
                app.RadDLabel_2.Visible='off';
                app.LambdaLabel.Visible='off';
                app.CLabel.Visible='off';
                app.aLabel.Visible='off';
                
                app.LambdaEditField.Visible='off';
                app.LambdaEditFieldLabel.Visible='off';
                app.Label.Visible='off';
                app.LambdaEditField_2.Visible='off';
                
                app.CEditField.Visible='off';
                app.CEditFieldLabel.Visible='off';
                app.Label_2.Visible='off';
                app.CEditField_2.Visible='off';
                
                app.aEditField.Visible='off';
                app.aEditFieldLabel.Visible='off';
                app.Label_3.Visible='off';
                app.aEditField_2.Visible='off';
                
                
            elseif strcmp(value,'Orifice')
                file=imread([pwd '\Optimization+Allocation\Orifice Weir'],'png');
                app.im1=file;
                image(app.UIAxes,app.im1)
                app.code=1;
                app.nvars=3;
                app.LambdaEditField.Visible='on';
                app.LambdaEditFieldLabel.Visible='on';
                app.LambdaEditFieldLabel.Text='Lambda';
                app.Label.Visible='on';
                app.LambdaEditField_2.Visible='on';
                
                app.CEditField.Visible='on';
                app.CEditFieldLabel.Visible='on';
                app.CEditFieldLabel.Text='C';
                app.Label_2.Visible='on';
                app.CEditField_2.Visible='on';
                
                app.aEditField.Visible='on';
                app.aEditFieldLabel.Visible='on';
                app.aEditFieldLabel.Text='a (m^2)';
                app.Label_3.Visible='on';
                app.aEditField_2.Visible='on';
                
                app.LambdaLabel_2.Visible='on';
                app.CLabel_2.Visible='on';
                app.aLabel_2.Visible='on';
                
                app.CLabel_4.Visible='off';
                app.SLabel.Visible='off';
                
                app.CLabel_5.Visible='off';
                app.LLabel.Visible='off';
                
                app.CLabel_6.Visible='off';
                app.TETALabel.Visible='off';
                
            elseif strcmp(value,'Proportional')
                file=imread([pwd '\Optimization+Allocation\Proportional Weir'],'png');
                app.im2=file;
                image(app.UIAxes,app.im2)
                app.code=2;
                app.nvars=2;
                app.LambdaEditField.Visible='on';
                app.LambdaEditFieldLabel.Visible='on';
                app.LambdaEditFieldLabel.Text='C';
                app.Label.Visible='on';
                app.LambdaEditField_2.Visible='on';
                
                app.CEditField.Visible='on';
                app.CEditFieldLabel.Visible='on';
                app.CEditFieldLabel.Text='S (m)';
                app.Label_2.Visible='on';
                app.CEditField_2.Visible='on';
                
                app.aEditField.Visible='off';
                app.aEditFieldLabel.Visible='off';
                app.aEditFieldLabel.Text='';
                app.Label_3.Visible='off';
                app.aEditField_2.Visible='off';
                
                app.LambdaLabel_2.Visible='off';
                app.CLabel_2.Visible='off';
                app.aLabel_2.Visible='off';
                
                app.CLabel_4.Visible='on';
                app.SLabel.Visible='on';
                
                app.CLabel_5.Visible='off';
                app.LLabel.Visible='off';
                
                app.CLabel_6.Visible='off';
                app.TETALabel.Visible='off';
                
            elseif strcmp(value,'Rectangular')
                file=imread([pwd '\Optimization+Allocation\Rectangular Weir'],'png');
                app.im3=file;
                image(app.UIAxes,app.im3)
                app.code=3;
                app.nvars=2;
                app.LambdaEditField.Visible='on';
                app.LambdaEditFieldLabel.Visible='on';
                app.LambdaEditFieldLabel.Text='C';
                app.Label.Visible='on';
                app.LambdaEditField_2.Visible='on';
                
                app.CEditField.Visible='on';
                app.CEditFieldLabel.Visible='on';
                app.CEditFieldLabel.Text='L (m)';
                app.Label_2.Visible='on';
                app.CEditField_2.Visible='on';
                
                app.aEditField.Visible='off';
                app.aEditFieldLabel.Visible='off';
                app.aEditFieldLabel.Text='';
                app.Label_3.Visible='off';
                app.aEditField_2.Visible='off';
                
                app.LambdaLabel_2.Visible='off';
                app.CLabel_2.Visible='off';
                app.aLabel_2.Visible='off';
                
                app.CLabel_4.Visible='off';
                app.SLabel.Visible='off';
                
                app.CLabel_5.Visible='on';
                app.LLabel.Visible='on';
                
                app.CLabel_6.Visible='off';
                app.TETALabel.Visible='off';
                
            elseif strcmp(value,'Triangular')
                file=imread([pwd '\Optimization+Allocation\Triangular Weir'],'png');
                app.im4=file;
                image(app.UIAxes,app.im4)
                app.code=4;
                app.nvars=2;
                app.LambdaEditField.Visible='on';
                app.LambdaEditFieldLabel.Visible='on';
                app.LambdaEditFieldLabel.Text='C';
                app.Label.Visible='on';
                app.LambdaEditField_2.Visible='on';
                
                app.CEditField.Visible='on';
                app.CEditFieldLabel.Visible='on';
                app.CEditFieldLabel.Text='Theta (deg)';
                app.Label_2.Visible='on';
                app.CEditField_2.Visible='on';
                
                app.aEditField.Visible='off';
                app.aEditFieldLabel.Visible='off';
                app.aEditFieldLabel.Text='';
                app.Label_3.Visible='off';
                app.aEditField_2.Visible='off';
                
                app.LambdaLabel_2.Visible='off';
                app.CLabel_2.Visible='off';
                app.aLabel_2.Visible='off';
                
                app.CLabel_4.Visible='off';
                app.SLabel.Visible='off';
                
                app.CLabel_5.Visible='off';
                app.LLabel.Visible='off';
                
                app.CLabel_6.Visible='on';
                app.TETALabel.Visible='on';
                
            end
            
        end

        % Selection changed function: RiskMethodsButtonGroup
        function RiskMethodsButtonGroupSelectionChanged(app, event)
            selectedButton = app.RiskMethodsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'CVaR')
                app.cvnp=1;
                % Objective Funcions of CVaR
                app.MinimizeConditionalValueatRiskforLabel.Visible='off';
                app.HeadCVaRHLabel.Visible='off';
                app.OutflowCVaRQLabel.Visible='off';
                app.DeficitCVaRDLabel.Visible='off';
                app.MinimizeLabel.Visible='off';
                % orifice
                app.LambdaLabel_3.Visible='off';
                app.CLabel_7.Visible='off';
                app.aLabel_3.Visible='off';
                % Proportional
                app.CLabel_9.Visible='off';
                app.SLabel_2.Visible='off';
                app.aLabel_3.Visible='off';
                % Rectangular
                app.CLabel_10.Visible='off';
                app.LLabel_2.Visible='off';
                app.aLabel_3.Visible='off';
                % Triangular
                app.CLabel_11.Visible='off';
                app.TETALabel_2.Visible='off';
                app.aLabel_3.Visible='off';
                
                % Objective Functions of NINP
                app.MinimizeLabel_2.Visible='off';
                app.RadiusofHeadRadHLabel.Visible='off';
                app.MeanofHmeanHLabel.Visible='off';
                app.RadiusofOutflowRadQLabel.Visible='off';
                app.MeanofOutflowmeanQLabel.Visible='off';
                app.RadiusofDeficitRadDLabel.Visible='off';
                app.MeanofDeficitmeanDLabel.Visible='off';
                app.MinimizeLabel_3.Visible='off';
                % Orifice
                app.LambdaLabel_4.Visible='off';
                app.CLabel_8.Visible='off';
                app.aLabel_4.Visible='off';
                % Proportional
                app.CLabel_12.Visible='off';
                app.SLabel_3.Visible='off';
                % Rectangular
                app.CLabel_13.Visible='off';
                app.LLabel_3.Visible='off';
                % Triangular
                app.CLabel_14.Visible='off';
                app.TETALabel_3.Visible='off';
                
                %Objective Functions of CVaR+NINP
                app.MinimizeConditionalValueatRiskforLabel_2.Visible='off';
                app.HeadCVaRHLabel_2.Visible='off';
                app.OutflowCVaRQLabel_2.Visible='off';
                app.DeficitCVaRDLabel_2.Visible='off';
                app.MinimizeLabel_4.Visible='off';
                app.RadiusofHeadRadHLabel_2.Visible='off';
                app.MeanofHmeanHLabel_2.Visible='off';
                app.RadiusofOutflowRadQLabel_2.Visible='off';
                app.MeanofOutflowmeanQLabel_2.Visible='off';
                app.RadiusofDeficitRadDLabel_2.Visible='off';
                app.MeanofDeficitmeanDLabel_2.Visible='off';
                app.MinimizeLabel_5.Visible='off';
                % Orifice
                app.LambdaLabel_5.Visible='off';
                app.CLabel_15.Visible='off';
                app.aLabel_5.Visible='off';
                % Propotional
                app.CLabel_16.Visible='off';
                app.SLabel_4.Visible='off';
                % Rectangular
                app.CLabel_17.Visible='off';
                app.LLabel_4.Visible='off';
                % Triangular
                app.CLabel_18.Visible='off';
                app.TETALabel_4.Visible='off';
            
                app.EditField.Visible='off';
                app.EditField_2.Visible='off';
                app.EditField_3.Visible='off';
                app.EditField_4.Visible='off';
                app.EditField_5.Visible='off';
                app.EditField_6.Visible='off';
                app.EditField_7.Visible='off';
                app.EditField_8.Visible='off';
                app.EditField_9.Visible='off';
                app.EditField_10.Visible='off';
                app.EditField_11.Visible='off';
                app.EditField_12.Visible='off';
                app.CVaRHLabel.Visible='off';
                app.CVaRQLabel.Visible='off';
                app.CVaRDLabel.Visible='off';
                app.meanHLabel_2.Visible='off';
                app.RadHLabel_2.Visible='off';
                app.meanQLabel_2.Visible='off';
                app.RadQLabel_2.Visible='off';
                app.meanDLabel_2.Visible='off';
                app.RadDLabel_2.Visible='off';
                app.LambdaLabel.Visible='off';
                app.CLabel.Visible='off';
                app.aLabel.Visible='off';
                
                app.EditField.Visible='on';
                app.EditField_2.Visible='on';
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
                app.EditField_5.Visible='on';
                app.CVaRHLabel.Text='CVaR H';
                app.CVaRHLabel.Visible='on';
                app.CVaRQLabel.Text='CVaR Q';
                app.CVaRQLabel.Visible='on';
                app.CVaRDLabel.Text='CVaR D';
                app.CVaRDLabel.Visible='on';
                if app.code==1
                    app.meanHLabel_2.Text='Lambda';
                    app.meanHLabel_2.Visible='on';
                    app.RadHLabel_2.Text='C';
                    app.RadHLabel_2.Visible='on';
                    app.meanQLabel_2.Text='a';
                    app.meanQLabel_2.Visible='on';
                    app.EditField_6.Visible='on';
                    
                    % Objective Funcions of CVaR
                    app.MinimizeConditionalValueatRiskforLabel.Visible='on';
                    app.HeadCVaRHLabel.Visible='on';
                    app.OutflowCVaRQLabel.Visible='on';
                    app.DeficitCVaRDLabel.Visible='on';
                    app.MinimizeLabel.Visible='on';
                    % orifice
                    app.LambdaLabel_3.Visible='on';
                    app.CLabel_7.Visible='on';
                    app.aLabel_3.Visible='on';
                    
                    
                elseif app.code==2
                    % Objective Funcions of CVaR
                    app.MinimizeConditionalValueatRiskforLabel.Visible='on';
                    app.HeadCVaRHLabel.Visible='on';
                    app.OutflowCVaRQLabel.Visible='on';
                    app.DeficitCVaRDLabel.Visible='on';
                    app.MinimizeLabel.Visible='on';
                    % orifice
                    app.LambdaLabel_3.Visible='off';
                    app.CLabel_7.Visible='off';
                    app.aLabel_3.Visible='off';
                    % Proportional
                    app.CLabel_9.Visible='on';
                    app.SLabel_2.Visible='on';
                
                    app.meanHLabel_2.Text='C';
                    app.meanHLabel_2.Visible='on';
                    app.RadHLabel_2.Text='S';
                    app.RadHLabel_2.Visible='on';
                    app.meanQLabel_2.Visible='off';
                    app.EditField_6.Visible='off';
                elseif app.code==3
                    % Objective Funcions of CVaR
                    app.MinimizeConditionalValueatRiskforLabel.Visible='on';
                    app.HeadCVaRHLabel.Visible='on';
                    app.OutflowCVaRQLabel.Visible='on';
                    app.DeficitCVaRDLabel.Visible='on';
                    app.MinimizeLabel.Visible='on';
                    % orifice
                    app.LambdaLabel_3.Visible='off';
                    app.CLabel_7.Visible='off';
                    app.aLabel_3.Visible='off';
                    % Proportional
                    app.CLabel_9.Visible='off';
                    app.SLabel_2.Visible='off';
                    % Rectangular
                    app.CLabel_10.Visible='on';
                    app.LLabel_2.Visible='on';
                    
                    app.meanHLabel_2.Text='C';
                    app.meanHLabel_2.Visible='on';
                    app.RadHLabel_2.Text='L';
                    app.RadHLabel_2.Visible='on';
                    app.meanQLabel_2.Visible='off';
                    app.EditField_6.Visible='off';
                elseif app.code==4
                    % Objective Funcions of CVaR
                    app.MinimizeConditionalValueatRiskforLabel.Visible='on';
                    app.HeadCVaRHLabel.Visible='on';
                    app.OutflowCVaRQLabel.Visible='on';
                    app.DeficitCVaRDLabel.Visible='on';
                    app.MinimizeLabel.Visible='on';
                    % orifice
                    app.LambdaLabel_3.Visible='off';
                    app.CLabel_7.Visible='off';
                    app.aLabel_3.Visible='off';
                    % Proportional
                    app.CLabel_9.Visible='off';
                    app.SLabel_2.Visible='off';
                    % Rectangular
                    app.CLabel_10.Visible='off';
                    app.LLabel_2.Visible='off';
                    % Triangular
                    app.CLabel_11.Visible='on';
                    app.TETALabel_2.Visible='on';
                    
                    app.meanHLabel_2.Text='C';
                    app.meanHLabel_2.Visible='on';
                    app.RadHLabel_2.Text='TETA';
                    app.RadHLabel_2.Visible='on';
                    app.meanQLabel_2.Visible='off';
                    app.EditField_6.Visible='off';
                end
                
            elseif strcmp(selectedButton.Text,'NINP')
                app.cvnp=2;
                % Objective Funcions of CVaR
                app.MinimizeConditionalValueatRiskforLabel.Visible='off';
                app.HeadCVaRHLabel.Visible='off';
                app.OutflowCVaRQLabel.Visible='off';
                app.DeficitCVaRDLabel.Visible='off';
                app.MinimizeLabel.Visible='off';
                % orifice
                app.LambdaLabel_3.Visible='off';
                app.CLabel_7.Visible='off';
                app.aLabel_3.Visible='off';
                % Proportional
                app.CLabel_9.Visible='off';
                app.SLabel_2.Visible='off';
                app.aLabel_3.Visible='off';
                % Rectangular
                app.CLabel_10.Visible='off';
                app.LLabel_2.Visible='off';
                app.aLabel_3.Visible='off';
                % Triangular
                app.CLabel_11.Visible='off';
                app.TETALabel_2.Visible='off';
                app.aLabel_3.Visible='off';
                
                % Objective Functions of NINP
                app.MinimizeLabel_2.Visible='off';
                app.RadiusofHeadRadHLabel.Visible='off';
                app.MeanofHmeanHLabel.Visible='off';
                app.RadiusofOutflowRadQLabel.Visible='off';
                app.MeanofOutflowmeanQLabel.Visible='off';
                app.RadiusofDeficitRadDLabel.Visible='off';
                app.MeanofDeficitmeanDLabel.Visible='off';
                app.MinimizeLabel_3.Visible='off';
                % Orifice
                app.LambdaLabel_4.Visible='off';
                app.CLabel_8.Visible='off';
                app.aLabel_4.Visible='off';
                % Proportional
                app.CLabel_12.Visible='off';
                app.SLabel_3.Visible='off';
                % Rectangular
                app.CLabel_13.Visible='off';
                app.LLabel_3.Visible='off';
                % Triangular
                app.CLabel_14.Visible='off';
                app.TETALabel_3.Visible='off';
                
                %Objective Functions of CVaR+NINP
                app.MinimizeConditionalValueatRiskforLabel_2.Visible='off';
                app.HeadCVaRHLabel_2.Visible='off';
                app.OutflowCVaRQLabel_2.Visible='off';
                app.DeficitCVaRDLabel_2.Visible='off';
                app.MinimizeLabel_4.Visible='off';
                app.RadiusofHeadRadHLabel_2.Visible='off';
                app.MeanofHmeanHLabel_2.Visible='off';
                app.RadiusofOutflowRadQLabel_2.Visible='off';
                app.MeanofOutflowmeanQLabel_2.Visible='off';
                app.RadiusofDeficitRadDLabel_2.Visible='off';
                app.MeanofDeficitmeanDLabel_2.Visible='off';
                app.MinimizeLabel_5.Visible='off';
                % Orifice
                app.LambdaLabel_5.Visible='off';
                app.CLabel_15.Visible='off';
                app.aLabel_5.Visible='off';
                % Propotional
                app.CLabel_16.Visible='off';
                app.SLabel_4.Visible='off';
                % Rectangular
                app.CLabel_17.Visible='off';
                app.LLabel_4.Visible='off';
                % Triangular
                app.CLabel_18.Visible='off';
                app.TETALabel_4.Visible='off';
                
                app.EditField.Visible='off';
                app.EditField_2.Visible='off';
                app.EditField_3.Visible='off';
                app.EditField_4.Visible='off';
                app.EditField_5.Visible='off';
                app.EditField_6.Visible='off';
                app.EditField_7.Visible='off';
                app.EditField_8.Visible='off';
                app.EditField_9.Visible='off';
                app.EditField_10.Visible='off';
                app.EditField_11.Visible='off';
                app.EditField_12.Visible='off';
                app.CVaRHLabel.Visible='off';
                app.CVaRQLabel.Visible='off';
                app.CVaRDLabel.Visible='off';
                app.meanHLabel_2.Visible='off';
                app.RadHLabel_2.Visible='off';
                app.meanQLabel_2.Visible='off';
                app.RadQLabel_2.Visible='off';
                app.meanDLabel_2.Visible='off';
                app.RadDLabel_2.Visible='off';
                app.LambdaLabel.Visible='off';
                app.CLabel.Visible='off';
                app.aLabel.Visible='off';
                
                app.EditField.Visible='on';
                app.EditField_2.Visible='on';
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
                app.EditField_5.Visible='on';
                app.EditField_6.Visible='on';
                app.EditField_7.Visible='on';
                app.EditField_8.Visible='on';
                app.CVaRHLabel.Text='mean H';
                app.CVaRHLabel.Visible='on';
                app.CVaRQLabel.Text='Rad H';
                app.CVaRQLabel.Visible='on';
                app.CVaRDLabel.Text='mean Q';
                app.CVaRDLabel.Visible='on';
                app.meanHLabel_2.Text='Rad Q';
                app.meanHLabel_2.Visible='on';
                app.RadHLabel_2.Text='mean D';
                app.RadHLabel_2.Visible='on';
                app.meanQLabel_2.Text='Rad D';
                app.meanQLabel_2.Visible='on';
                
                % Objective Functions of NINP
                app.MinimizeLabel_2.Visible='on';
                app.RadiusofHeadRadHLabel.Visible='on';
                app.MeanofHmeanHLabel.Visible='on';
                app.RadiusofOutflowRadQLabel.Visible='on';
                app.MeanofOutflowmeanQLabel.Visible='on';
                app.RadiusofDeficitRadDLabel.Visible='on';
                app.MeanofDeficitmeanDLabel.Visible='on';
                app.MinimizeLabel_3.Visible='on';
                
                if app.code==1
                    app.RadQLabel_2.Text='Lambda';
                    app.RadQLabel_2.Visible='on';
                    app.meanDLabel_2.Text='C';
                    app.meanDLabel_2.Visible='on';
                    app.RadDLabel_2.Text='a';
                    app.RadDLabel_2.Visible='on';
                    app.EditField_9.Visible='on';
                    % Orifice
                    app.LambdaLabel_4.Visible='on';
                    app.CLabel_8.Visible='on';
                    app.aLabel_4.Visible='on';
                elseif app.code==2
                    app.RadQLabel_2.Text='C';
                    app.RadQLabel_2.Visible='on';
                    app.meanDLabel_2.Text='S';
                    app.meanDLabel_2.Visible='on';
                    app.RadDLabel_2.Visible='off';
                    app.EditField_9.Visible='off';
                    % Proportional
                    app.CLabel_12.Visible='on';
                    app.SLabel_3.Visible='on';
                    app.aLabel_4.Visible='off';
                elseif app.code==3
                    app.RadQLabel_2.Text='C';
                    app.RadQLabel_2.Visible='on';
                    app.meanDLabel_2.Text='L';
                    app.meanDLabel_2.Visible='on';
                    app.RadDLabel_2.Visible='off';
                    app.EditField_9.Visible='off';
                    % Rectangular
                    app.CLabel_13.Visible='on';
                    app.LLabel_3.Visible='on';
                    app.aLabel_4.Visible='off';
                elseif app.code==4
                    app.RadQLabel_2.Text='C';
                    app.RadQLabel_2.Visible='on';
                    app.meanDLabel_2.Text='TETA';
                    app.meanDLabel_2.Visible='on';
                    app.RadDLabel_2.Visible='off';
                    app.EditField_9.Visible='off';
                    % Triangular
                    app.CLabel_14.Visible='on';
                    app.TETALabel_3.Visible='on';
                    app.aLabel_4.Visible='off';
                end
            elseif strcmp(selectedButton.Text,'CVaR+NINP')
                app.cvnp=3;
                % Objective Funcions of CVaR
                app.MinimizeConditionalValueatRiskforLabel.Visible='off';
                app.HeadCVaRHLabel.Visible='off';
                app.OutflowCVaRQLabel.Visible='off';
                app.DeficitCVaRDLabel.Visible='off';
                app.MinimizeLabel.Visible='off';
                % orifice
                app.LambdaLabel_3.Visible='off';
                app.CLabel_7.Visible='off';
                app.aLabel_3.Visible='off';
                % Proportional
                app.CLabel_9.Visible='off';
                app.SLabel_2.Visible='off';
                app.aLabel_3.Visible='off';
                % Rectangular
                app.CLabel_10.Visible='off';
                app.LLabel_2.Visible='off';
                app.aLabel_3.Visible='off';
                % Triangular
                app.CLabel_11.Visible='off';
                app.TETALabel_2.Visible='off';
                app.aLabel_3.Visible='off';
                
                % Objective Functions of NINP
                app.MinimizeLabel_2.Visible='off';
                app.RadiusofHeadRadHLabel.Visible='off';
                app.MeanofHmeanHLabel.Visible='off';
                app.RadiusofOutflowRadQLabel.Visible='off';
                app.MeanofOutflowmeanQLabel.Visible='off';
                app.RadiusofDeficitRadDLabel.Visible='off';
                app.MeanofDeficitmeanDLabel.Visible='off';
                app.MinimizeLabel_3.Visible='off';
                % Orifice
                app.LambdaLabel_4.Visible='off';
                app.CLabel_8.Visible='off';
                app.aLabel_4.Visible='off';
                % Proportional
                app.CLabel_12.Visible='off';
                app.SLabel_3.Visible='off';
                % Rectangular
                app.CLabel_13.Visible='off';
                app.LLabel_3.Visible='off';
                % Triangular
                app.CLabel_14.Visible='off';
                app.TETALabel_3.Visible='off';
                
                %Objective Functions of CVaR+NINP
                app.MinimizeConditionalValueatRiskforLabel_2.Visible='off';
                app.HeadCVaRHLabel_2.Visible='off';
                app.OutflowCVaRQLabel_2.Visible='off';
                app.DeficitCVaRDLabel_2.Visible='off';
                app.MinimizeLabel_4.Visible='off';
                app.RadiusofHeadRadHLabel_2.Visible='off';
                app.MeanofHmeanHLabel_2.Visible='off';
                app.RadiusofOutflowRadQLabel_2.Visible='off';
                app.MeanofOutflowmeanQLabel_2.Visible='off';
                app.RadiusofDeficitRadDLabel_2.Visible='off';
                app.MeanofDeficitmeanDLabel_2.Visible='off';
                app.MinimizeLabel_5.Visible='off';
                % Orifice
                app.LambdaLabel_5.Visible='off';
                app.CLabel_15.Visible='off';
                app.aLabel_5.Visible='off';
                % Propotional
                app.CLabel_16.Visible='off';
                app.SLabel_4.Visible='off';
                % Rectangular
                app.CLabel_17.Visible='off';
                app.LLabel_4.Visible='off';
                % Triangular
                app.CLabel_18.Visible='off';
                app.TETALabel_4.Visible='off';
                
                app.EditField.Visible='off';
                app.EditField_2.Visible='off';
                app.EditField_3.Visible='off';
                app.EditField_4.Visible='off';
                app.EditField_5.Visible='off';
                app.EditField_6.Visible='off';
                app.EditField_7.Visible='off';
                app.EditField_8.Visible='off';
                app.EditField_9.Visible='off';
                app.EditField_10.Visible='off';
                app.EditField_11.Visible='off';
                app.EditField_12.Visible='off';
                app.CVaRHLabel.Visible='off';
                app.CVaRQLabel.Visible='off';
                app.CVaRDLabel.Visible='off';
                app.meanHLabel_2.Visible='off';
                app.RadHLabel_2.Visible='off';
                app.meanQLabel_2.Visible='off';
                app.RadQLabel_2.Visible='off';
                app.meanDLabel_2.Visible='off';
                app.RadDLabel_2.Visible='off';
                app.LambdaLabel.Visible='off';
                app.CLabel.Visible='off';
                app.aLabel.Visible='off';
                
                app.EditField.Visible='on';
                app.EditField_2.Visible='on';
                app.EditField_3.Visible='on';
                app.EditField_4.Visible='on';
                app.EditField_5.Visible='on';
                app.EditField_6.Visible='on';
                app.EditField_7.Visible='on';
                app.EditField_8.Visible='on';
                app.EditField_9.Visible='on';
                app.EditField_10.Visible='on';
                app.EditField_11.Visible='on';
                app.CVaRHLabel.Text='CVaR H';
                app.CVaRHLabel.Visible='on';
                app.CVaRQLabel.Text='CVaR Q';
                app.CVaRQLabel.Visible='on';
                app.CVaRDLabel.Text='CVaR D';
                app.CVaRDLabel.Visible='on';
                app.meanHLabel_2.Text='mean H';
                app.meanHLabel_2.Visible='on';
                app.RadHLabel_2.Text='Rad H';
                app.RadHLabel_2.Visible='on';
                app.meanQLabel_2.Text='mean Q';
                app.meanQLabel_2.Visible='on';
                app.RadQLabel_2.Text='Rad Q';
                app.RadQLabel_2.Visible='on';
                app.meanDLabel_2.Text='mean D';
                app.meanDLabel_2.Visible='on';
                app.RadDLabel_2.Text='Rad D';
                app.RadDLabel_2.Visible='on';
                %Objective Functions of CVaR+NINP
                app.MinimizeConditionalValueatRiskforLabel_2.Visible='on';
                app.HeadCVaRHLabel_2.Visible='on';
                app.OutflowCVaRQLabel_2.Visible='on';
                app.DeficitCVaRDLabel_2.Visible='on';
                app.MinimizeLabel_4.Visible='on';
                app.RadiusofHeadRadHLabel_2.Visible='on';
                app.MeanofHmeanHLabel_2.Visible='on';
                app.RadiusofOutflowRadQLabel_2.Visible='on';
                app.MeanofOutflowmeanQLabel_2.Visible='on';
                app.RadiusofDeficitRadDLabel_2.Visible='on';
                app.MeanofDeficitmeanDLabel_2.Visible='on';
                app.MinimizeLabel_5.Visible='on';
                if app.code==1
                    % Orifice
                    app.LambdaLabel_5.Visible='on';
                    app.CLabel_15.Visible='on';
                    app.aLabel_5.Visible='on';
                    
                    app.LambdaLabel.Text='Lambda';
                    app.LambdaLabel.Visible='on';
                    app.CLabel.Text='C';
                    app.CLabel.Visible='on';
                    app.aLabel.Text='a';
                    app.aLabel.Visible='on';
                    app.EditField_12.Visible='on';
                elseif app.code==2
                    % Propotional
                    app.CLabel_16.Visible='on';
                    app.SLabel_4.Visible='on';
                    app.aLabel_5.Visible='off';
                    
                    app.LambdaLabel.Text='C';
                    app.LambdaLabel.Visible='on';
                    app.CLabel.Text='S';
                    app.CLabel.Visible='on';
                    app.aLabel.Visible='off';
                    app.EditField_12.Visible='off';
                elseif app.code==3
                    % Rectangular
                    app.CLabel_17.Visible='on';
                    app.LLabel_4.Visible='on';
                    app.aLabel_5.Visible='off';
                    
                    app.LambdaLabel.Text='C';
                    app.LambdaLabel.Visible='on';
                    app.CLabel.Text='L';
                    app.CLabel.Visible='on';
                    app.aLabel.Visible='off';
                    app.EditField_12.Visible='off';
                elseif app.code==4
                    % Triangular
                    app.CLabel_18.Visible='on';
                    app.TETALabel_4.Visible='on';
                    app.aLabel_5.Visible='off';
                    
                    app.LambdaLabel.Text='C';
                    app.LambdaLabel.Visible='on';
                    app.CLabel.Text='TETA';
                    app.CLabel.Visible='on';
                    app.aLabel.Visible='off';
                    app.EditField_12.Visible='off';
                end
                
            end
        end

        % Value changed function: AreaKm2EditField
        function AreaKm2EditFieldValueChanged(app, event)
            app.area = app.AreaKm2EditField.Value;
        end

        % Value changed function: gm2secEditField
        function gm2secEditFieldValueChanged(app, event)
            app.grav = app.gm2secEditField.Value;
        end

        % Value changed function: dtsecEditField
        function dtsecEditFieldValueChanged(app, event)
            app.deltat = app.dtsecEditField.Value;
        end

        % Value changed function: PopulationMultiplierEditField
        function PopulationMultiplierEditFieldValueChanged(app, event)
            app.popmulti = app.PopulationMultiplierEditField.Value;
        end

        % Value changed function: GenerationsEditField
        function GenerationsEditFieldValueChanged(app, event)
            app.generation = app.GenerationsEditField.Value;
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            app.alpha = app.alphaEditField.Value;
        end

        % Value changed function: LambdaEditField
        function LambdaEditFieldValueChanged(app, event)
            app.lb1 = app.LambdaEditField.Value;
        end

        % Value changed function: LambdaEditField_2
        function LambdaEditField_2ValueChanged(app, event)
            app.ub1 = app.LambdaEditField_2.Value;
            
        end

        % Value changed function: CEditField
        function CEditFieldValueChanged(app, event)
            app.lb2 = app.CEditField.Value;
            
        end

        % Value changed function: CEditField_2
        function CEditField_2ValueChanged(app, event)
            app.ub2 = app.CEditField_2.Value;
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            app.lb3 = app.aEditField.Value;
            
        end

        % Value changed function: aEditField_2
        function aEditField_2ValueChanged(app, event)
            app.ub3 = app.aEditField_2.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.InitializingLabel.Visible='off';
            app.RunningLabel.Visible='on';
            app.OPTIMIZATIONCOMPELETELabel.Visible='off';
            addpath([pwd,'\Optimization+Allocation\'])
            if app.nvars==2
                app.lb={app.lb1,app.lb2};
                app.ub={app.ub1,app.ub2};
            elseif app.nvars==3
                app.lb={app.lb1,app.lb2,app.lb3};
                app.ub={app.ub1,app.ub2,app.ub3};
            end
            if app.code==1
                app.InitializingLabel.Visible='off';
                app.RunningLabel.Visible='on';
                app.OPTIMIZATIONCOMPELETELabel.Visible='off';
                [app.y,app.lambda_val,app.C_val,app.a_val,app.H,app.Q,app.HH,...
                    app.QQ,app.DEF]=MultiObj_NSGAII_O(app.cvnp,...
                    app.deltat,app.area,app.grav,app.alpha,app.lb,app.ub,...
                    app.nvars,app.popmulti,app.generation,app.mm);
                y=app.y;
                lambda_val=app.lambda_val;
                C_val=app.C_val;
                a_val=app.a_val;
                H=app.H;
                Q=app.Q;
                HH=app.HH;
                QQ=app.QQ;
                DEF=app.DEF;
                save([pwd '\Results\Orifice\Orifice_Weir_' app.namem '.mat'],'y','lambda_val','C_val',...
                    'a_val','H','Q','HH','QQ','DEF')
                numf=size(app.lambda_val,1);
                charf=cell(1,numf+1);
                for i=1:(numf+1)
                    if i==1
                        charf{i}=' ';
                    else
                        charf{i}=num2str(i-1);
                    end
                end
                app.ParetoDropDown.Items=charf;
                app.ParetoDropDown.ItemsData=charf;
            elseif app.code==2
                app.InitializingLabel.Visible='off';
                app.RunningLabel.Visible='on';
                app.OPTIMIZATIONCOMPELETELabel.Visible='off';
                [app.y,app.C_val,app.s_val,app.H,app.Q,app.HH,...
                    app.QQ,app.DEF]=...
                    MultiObj_NSGAII_P(app.cvnp,...
                    app.deltat,app.area,app.grav,app.alpha,app.lb,app.ub,...
                    app.nvars,app.popmulti,app.generation,app.mm);
                y=app.y;
                C_val=app.C_val;
                s_val=app.s_val;
                H=app.H;
                Q=app.Q;
                HH=app.HH;
                QQ=app.QQ;
                DEF=app.DEF;
                save([pwd '\Results\Proportional\Proportional_Weir_' app.namem '.mat'],'y','C_val','s_val',...
                    'H','Q','HH','QQ','DEF')
                numf=size(app.C_val,1);
                charf=cell(1,numf+1);
                for i=1:(numf+1)
                    if i==1
                        charf{i}=' ';
                    else
                        charf{i}=num2str(i-1);
                    end
                end
                app.ParetoDropDown.Items=charf;
                app.ParetoDropDown.ItemsData=charf;
            elseif app.code==3
                app.InitializingLabel.Visible='off';
                app.RunningLabel.Visible='on';
                app.OPTIMIZATIONCOMPELETELabel.Visible='off';
                [app.y,app.C_val,app.l_val,app.H,app.Q,app.HH,...
                    app.QQ,app.DEF]=...
                    MultiObj_NSGAII_R(app.cvnp,...
                    app.deltat,app.area,app.grav,app.alpha,app.lb,app.ub,...
                    app.nvars,app.popmulti,app.generation,app.mm);
                y=app.y;
                C_val=app.C_val;
                l_val=app.s_val;
                H=app.H;
                Q=app.Q;
                HH=app.HH;
                QQ=app.QQ;
                DEF=app.DEF;
                save([pwd '\Results\Rectangular\Rectangular_Weir_' app.namem '.mat'],'y','C_val','l_val',...
                    'H','Q','HH','QQ','DEF')
                numf=size(app.C_val,1);
                charf=cell(1,numf+1);
                for i=1:(numf+1)
                    if i==1
                        charf{i}=' ';
                    else
                        charf{i}=num2str(i-1);
                    end
                end
                app.ParetoDropDown.Items=charf;
                app.ParetoDropDown.ItemsData=charf;
            elseif app.code==4
                app.InitializingLabel.Visible='off';
                app.RunningLabel.Visible='on';
                app.OPTIMIZATIONCOMPELETELabel.Visible='off';
                [app.y,app.C_val,app.tet_val,app.H,app.Q,app.HH,...
                    app.QQ,app.DEF]=...
                    MultiObj_NSGAII_T(app.cvnp,...
                    app.deltat,app.area,app.grav,app.alpha,app.lb,app.ub,...
                    app.nvars,app.popmulti,app.generation,app.mm);
                y=app.y;
                C_val=app.C_val;
                tet_val=app.tet_val;
                H=app.H;
                Q=app.Q;
                HH=app.HH;
                QQ=app.QQ;
                DEF=app.DEF;
                save([pwd '\Results\Triangular\Triangular_Weir_' app.namem '.mat'],'y','C_val','tet_val',...
                    'H','Q','HH','QQ','DEF')
                numf=size(app.C_val,1);
                charf=cell(1,numf+1);
                for i=1:(numf+1)
                    if i==1
                        charf{i}=' ';
                    else
                        charf{i}=num2str(i-1);
                    end
                end
                app.ParetoDropDown.Items=charf;
                app.ParetoDropDown.ItemsData=charf;
            end
            app.InitializingLabel.Visible='off';
            app.RunningLabel.Visible='off';
            app.OPTIMIZATIONCOMPELETELabel.Visible='on';
        end

        % Value changed function: ParetoDropDown
        function ParetoDropDownValueChanged(app, event)
            val1 = app.ParetoDropDown.Value;
            app.val=str2double(val1);
            if app.code==1
                app.nnn='O';
                app.namew='Orifice';
                if app.cvnp==1
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                    app.EditField_6.Value=num2str(app.y(app.val,6),3);
                    
                elseif app.cvnp==2
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                    app.EditField_6.Value=num2str(app.y(app.val,6),3);
                    app.EditField_7.Value=num2str(app.y(app.val,7),3);
                    app.EditField_8.Value=num2str(app.y(app.val,8),3);
                    app.EditField_9.Value=num2str(app.y(app.val,9),3);
                elseif app.cvnp==3
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                    app.EditField_6.Value=num2str(app.y(app.val,6),3);
                    app.EditField_7.Value=num2str(app.y(app.val,7),3);
                    app.EditField_8.Value=num2str(app.y(app.val,8),3);
                    app.EditField_9.Value=num2str(app.y(app.val,9),3);
                    app.EditField_10.Value=num2str(app.y(app.val,10),3);
                    app.EditField_11.Value=num2str(app.y(app.val,11),3);
                    app.EditField_12.Value=num2str(app.y(app.val,12),3);
                end
            elseif app.code==2
                app.nnn='P';
                app.namew='Proportional';
                if app.cvnp==1
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                elseif app.cvnp==2
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                    app.EditField_6.Value=num2str(app.y(app.val,6),3);
                    app.EditField_7.Value=num2str(app.y(app.val,7),3);
                    app.EditField_8.Value=num2str(app.y(app.val,8),3);
                elseif app.cvnp==3
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                    app.EditField_6.Value=num2str(app.y(app.val,6),3);
                    app.EditField_7.Value=num2str(app.y(app.val,7),3);
                    app.EditField_8.Value=num2str(app.y(app.val,8),3);
                    app.EditField_9.Value=num2str(app.y(app.val,9),3);
                    app.EditField_10.Value=num2str(app.y(app.val,10),3);
                    app.EditField_11.Value=num2str(app.y(app.val,11),3);
                end
            elseif app.code==3
                app.nnn='R';
                app.namew='Rectangular';
                if app.cvnp==1
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                elseif app.cvnp==2
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                    app.EditField_6.Value=num2str(app.y(app.val,6),3);
                    app.EditField_7.Value=num2str(app.y(app.val,7),3);
                    app.EditField_8.Value=num2str(app.y(app.val,8),3);
                elseif app.cvnp==3
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                    app.EditField_6.Value=num2str(app.y(app.val,6),3);
                    app.EditField_7.Value=num2str(app.y(app.val,7),3);
                    app.EditField_8.Value=num2str(app.y(app.val,8),3);
                    app.EditField_9.Value=num2str(app.y(app.val,9),3);
                    app.EditField_10.Value=num2str(app.y(app.val,10),3);
                    app.EditField_11.Value=num2str(app.y(app.val,11),3);
                end
            elseif app.code==4
                app.nnn='T';
                app.namew='Triangular';
                if app.cvnp==1
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                elseif app.cvnp==2
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                    app.EditField_6.Value=num2str(app.y(app.val,6),3);
                    app.EditField_7.Value=num2str(app.y(app.val,7),3);
                    app.EditField_8.Value=num2str(app.y(app.val,8),3);
                elseif app.cvnp==3
                    app.EditField.Value=num2str(app.y(app.val,1),3);
                    app.EditField_2.Value=num2str(app.y(app.val,2),3);
                    app.EditField_3.Value=num2str(app.y(app.val,3),3);
                    app.EditField_4.Value=num2str(app.y(app.val,4),3);
                    app.EditField_5.Value=num2str(app.y(app.val,5),3);
                    app.EditField_6.Value=num2str(app.y(app.val,6),3);
                    app.EditField_7.Value=num2str(app.y(app.val,7),3);
                    app.EditField_8.Value=num2str(app.y(app.val,8),3);
                    app.EditField_9.Value=num2str(app.y(app.val,9),3);
                    app.EditField_10.Value=num2str(app.y(app.val,10),3);
                    app.EditField_11.Value=num2str(app.y(app.val,11),3);
                end
            end
            app.H1=app.HH{app.val};
            app.Q1=app.QQ{app.val};
            app.Def1=app.DEF{app.val};
            maxih=max(app.H1,[],2);
            minih=min(app.H1,[],2);
            maxiq=max(app.Q1,[],2);
            miniq=min(app.Q1,[],2);
            tth=zeros(size(maxih,1),1);
            for i=1:size(tth,1)
                tth(i)=(i-1)*app.deltat;
            end
            ttq=zeros(size(maxiq,1),1);
            for i=1:size(ttq,1)
                ttq(i)=(i-1)*app.deltat;
            end
            % Polygon of H
            hp=[minih;flip(maxih);minih(1)];
            hp(isnan(hp))=0;
            qp=[miniq;flip(maxiq);miniq(1)];
            qp(isnan(qp))=0;
            thp=[tth;flip(tth);tth(1)];
            tqp=[ttq;flip(ttq);ttq(1)];
            pgn1=polyshape(thp,hp);
            pgn2=polyshape(tqp,qp);
            
            % Plot 1
            plot(app.UIAxes2,pgn1)
            xlim(app.UIAxes2,[min(thp),max(thp)]);
            ylim(app.UIAxes2,[0,ceil(max(hp))]);
            if app.val<10
                npar=['0' num2str(app.val)];
            else
                npar=num2str(app.val);
            end
            
            fs=12;
            lwib=1;
            
            h=figure;
            h.Visible='off';
            plot(pgn1)
            h.CurrentAxes.XLabel.String = app.UIAxes2.XLabel.String;
            h.CurrentAxes.YLabel.String = app.UIAxes2.YLabel.String;
            h.CurrentAxes.Title.String = app.UIAxes2.Title.String;
            box on
            ax=gca;
            ax.LineWidth=lwib;
            set(gca,'FontName','Times New Roman','FontSize',fs)
            h.CurrentAxes.XLim = [min(thp),max(thp)];
            h.CurrentAxes.YLim = [0,ceil(max(hp))];
            set(h,'PaperUnits','inches','PaperPosition',[0,0,10,5])
            set(gcf,'InvertHardCopy','off','color','w')
            saveas(h,['Results\' app.namew '\' app.namem '_' app.nnn 'PW' npar],'png')
            
            % Plot 2
            plot(app.UIAxes3,pgn2)
            xlim(app.UIAxes3,[min(tqp),max(tqp)]);
            ylim(app.UIAxes3,[0,ceil(max(qp))]);
            
            h1 = figure;
            h1.Visible = 'off';
            plot(pgn2)
            h1.CurrentAxes.XLabel.String = app.UIAxes3.XLabel.String;
            h1.CurrentAxes.YLabel.String = app.UIAxes3.YLabel.String;
            h1.CurrentAxes.Title.String = app.UIAxes3.Title.String;
            box on
            ax=gca;
            ax.LineWidth=lwib;
            set(gca,'FontName','Times New Roman','FontSize',fs)
            h1.CurrentAxes.XLim =[min(tqp),max(tqp)];
            h1.CurrentAxes.YLim=[0,ceil(max(qp))];
            set(h1,'PaperUnits','inches','PaperPosition',[0,0,10,5])
            set(gcf,'InvertHardCopy','off','color','w')
            saveas(h1,['Results\' app.namew '\' app.namem '_' app.nnn 'PQ' npar],'png')

            
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if strcmp(value,'Forward Euler')
                app.mm=1;
                app.namem='Forward_Euler';
            elseif strcmp(value,'Modified Euler')
                app.mm=2;
                app.namem='Modified_Euler';
            elseif strcmp(value,'Heun')
                app.mm=3;
                app.namem='Heun';
            elseif strcmp(value,'Ralston')
                app.mm=4;
                app.namem='Ralston';
            elseif strcmp(value,'Kutta 3rd order')
                app.mm=5;
                app.namem='Kutta_3rd_order';
            elseif strcmp(value,'A 3rd order')
                app.mm=6;
                app.namem='A_3rd_order';
            elseif strcmp(value,'Original Runge - Kutta')
                app.mm=7;
                app.namem='Original_Runge_Kutta';
            elseif strcmp(value,'3/8 rule')
                app.mm=8;
                app.namem='3_8_rule';
            elseif strcmp(value,'A 4th order')
                app.mm=9;
                app.namem='A_4th_order';
            elseif strcmp(value,'A 5th order')
                app.mm=10;
                app.namem='A_5th_order';
            elseif strcmp(value,'Heun - Euler')
                app.mm=11;
                app.namem='Heun_Euler';
            elseif strcmp(value,'Bogacki - Shampine')
                app.mm=12;
                app.namem='Bogacki_Shampine';
            elseif strcmp(value,'Runge - Kutta - Fehlberg')
                app.mm=13;
                app.namem='Runge_Kutta_Fehlberg';
            elseif strcmp(value,'Cash - Karp')
                app.mm=14;
                app.namem='Cash_Karp';
            elseif strcmp(value,'Dormand - Prince')
                app.mm=15;
                app.namem='Dormand_Prince';
            elseif strcmp(value,'Kutta - Merson')
                app.mm=16;
                app.namem='Kutta_Merson';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WeirDesignUIFigure = uifigure('Visible', 'off');
            app.WeirDesignUIFigure.Name = 'Weir Design';
            app.RunButton = uibutton(app.WeirDesignUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.WeirTypeandDecisionVaraiblesPanel = uipanel(app.WeirDesignUIFigure);
            app.WeirTypeandDecisionVaraiblesPanel.Title = 'Weir Type and Decision Varaibles';
            app.WeirTypeDropDownLabel = uilabel(app.WeirTypeandDecisionVaraiblesPanel);
            app.WeirTypeDropDownLabel.Text = 'Weir Type';
            app.WeirTypeDropDown = uidropdown(app.WeirTypeandDecisionVaraiblesPanel);
            app.WeirTypeDropDown.Items = {' ', 'Orifice', 'Proportional', 'Rectangular', 'Triangular'};
            app.WeirTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WeirTypeDropDownValueChanged, true);
            app.WeirTypeDropDown.Value = ' ';
            app.DecisionVariablesPanel = uipanel(app.WeirTypeandDecisionVaraiblesPanel);
            app.DecisionVariablesPanel.Title = 'Decision Variables';
            app.LambdaLabel_2 = uilabel(app.DecisionVariablesPanel);
            app.LambdaLabel_2.Text = '1. Lambda';
            app.CLabel_2 = uilabel(app.DecisionVariablesPanel);
            app.CLabel_2.Text = '2. C';
            app.aLabel_2 = uilabel(app.DecisionVariablesPanel);
            app.aLabel_2.Text = '3. a';
            app.CLabel_4 = uilabel(app.DecisionVariablesPanel);
            app.CLabel_4.Text = '1. C';
            app.SLabel = uilabel(app.DecisionVariablesPanel);
            app.SLabel.Text = '2. S';
            app.CLabel_5 = uilabel(app.DecisionVariablesPanel);
            app.CLabel_5.Text = '1. C';
            app.LLabel = uilabel(app.DecisionVariablesPanel);
            app.LLabel.Text = '2. L';
            app.CLabel_6 = uilabel(app.DecisionVariablesPanel);
            app.CLabel_6.Text = '1. C';
            app.TETALabel = uilabel(app.DecisionVariablesPanel);
            app.TETALabel.Text = '2. TETA';
            app.ModelInputsPanel = uipanel(app.WeirDesignUIFigure);
            app.ModelInputsPanel.Title = 'Model Inputs';
            app.AreaKm2EditFieldLabel = uilabel(app.ModelInputsPanel);
            app.AreaKm2EditFieldLabel.Text = 'Area (Km^2)';
            app.AreaKm2EditField = uieditfield(app.ModelInputsPanel, 'numeric');
            app.AreaKm2EditField.ValueChangedFcn = createCallbackFcn(app, @AreaKm2EditFieldValueChanged, true);
            app.dtsecEditFieldLabel = uilabel(app.ModelInputsPanel);
            app.dtsecEditFieldLabel.Text = 'dt (sec)';
            app.dtsecEditField = uieditfield(app.ModelInputsPanel, 'numeric');
            app.dtsecEditField.ValueChangedFcn = createCallbackFcn(app, @dtsecEditFieldValueChanged, true);
            app.gm2secEditFieldLabel = uilabel(app.ModelInputsPanel);
            app.gm2secEditFieldLabel.Text = 'g (m^2/sec)';
            app.gm2secEditField = uieditfield(app.ModelInputsPanel, 'numeric');
            app.gm2secEditField.ValueChangedFcn = createCallbackFcn(app, @gm2secEditFieldValueChanged, true);
            app.alphaEditFieldLabel = uilabel(app.ModelInputsPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.ModelInputsPanel, 'numeric');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.MethodDropDownLabel = uilabel(app.ModelInputsPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.ModelInputsPanel);
            app.MethodDropDown.Items = {' ', 'Forward Euler', 'Modified Euler', 'Heun', 'Ralston', 'Kutta 3rd order', 'A 3rd order', 'Original Runge - Kutta', '3/8 rule', 'A 4th order', 'A 5th order', 'Heun - Euler', 'Bogacki - Shampine', 'Runge - Kutta - Fehlberg', 'Cash - Karp', 'Dormand - Prince', 'Kutta - Merson'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = ' ';
            app.OptimizationInputsPanel = uipanel(app.WeirDesignUIFigure);
            app.OptimizationInputsPanel.Title = 'Optimization Inputs';
            app.LowerBoundLabel = uilabel(app.OptimizationInputsPanel);
            app.LowerBoundLabel.Text = 'Lower Bound';
            app.UpperBoundLabel = uilabel(app.OptimizationInputsPanel);
            app.UpperBoundLabel.Text = 'Upper Bound';
            app.LambdaEditFieldLabel = uilabel(app.OptimizationInputsPanel);
            app.LambdaEditFieldLabel.Visible = 'off';
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.OptimizationInputsPanel, 'numeric');
            app.LambdaEditField.ValueChangedFcn = createCallbackFcn(app, @LambdaEditFieldValueChanged, true);
            app.LambdaEditField.Visible = 'off';
            app.LambdaEditField_2 = uieditfield(app.OptimizationInputsPanel, 'numeric');
            app.LambdaEditField_2.ValueChangedFcn = createCallbackFcn(app, @LambdaEditField_2ValueChanged, true);
            app.LambdaEditField_2.Visible = 'off';
            app.CEditFieldLabel = uilabel(app.OptimizationInputsPanel);
            app.CEditFieldLabel.Visible = 'off';
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.OptimizationInputsPanel, 'numeric');
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @CEditFieldValueChanged, true);
            app.CEditField.Visible = 'off';
            app.CEditField_2 = uieditfield(app.OptimizationInputsPanel, 'numeric');
            app.CEditField_2.ValueChangedFcn = createCallbackFcn(app, @CEditField_2ValueChanged, true);
            app.CEditField_2.Visible = 'off';
            app.aEditFieldLabel = uilabel(app.OptimizationInputsPanel);
            app.aEditFieldLabel.Visible = 'off';
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.OptimizationInputsPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Visible = 'off';
            app.aEditField_2 = uieditfield(app.OptimizationInputsPanel, 'numeric');
            app.aEditField_2.ValueChangedFcn = createCallbackFcn(app, @aEditField_2ValueChanged, true);
            app.aEditField_2.Visible = 'off';
            app.WeirParametersLabel = uilabel(app.OptimizationInputsPanel);
            app.WeirParametersLabel.Text = 'Weir Parameters';
            app.Label = uilabel(app.OptimizationInputsPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '—';
            app.Label_2 = uilabel(app.OptimizationInputsPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '—';
            app.Label_3 = uilabel(app.OptimizationInputsPanel);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '—';
            app.PopulationMultiplierEditFieldLabel = uilabel(app.OptimizationInputsPanel);
            app.PopulationMultiplierEditFieldLabel.Text = 'Population Multiplier';
            app.PopulationMultiplierEditField = uieditfield(app.OptimizationInputsPanel, 'numeric');
            app.PopulationMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @PopulationMultiplierEditFieldValueChanged, true);
            app.GenerationsEditFieldLabel = uilabel(app.OptimizationInputsPanel);
            app.GenerationsEditFieldLabel.Text = 'Generations';
            app.GenerationsEditField = uieditfield(app.OptimizationInputsPanel, 'numeric');
            app.GenerationsEditField.ValueChangedFcn = createCallbackFcn(app, @GenerationsEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.WeirDesignUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.StatusPanel = uipanel(app.WeirDesignUIFigure);
            app.StatusPanel.Title = 'Status';
            app.InitializingLabel = uilabel(app.StatusPanel);
            app.InitializingLabel.Text = 'Initializing....';
            app.RunningLabel = uilabel(app.StatusPanel);
            app.RunningLabel.Text = 'Running...';
            app.OPTIMIZATIONCOMPELETELabel = uilabel(app.StatusPanel);
            app.OPTIMIZATIONCOMPELETELabel.Text = 'OPTIMIZATION COMPELETE!';
            app.ResultsPanel = uipanel(app.WeirDesignUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.EditField = uieditfield(app.ResultsPanel, 'text');
            app.CVaRHLabel = uilabel(app.ResultsPanel);
            app.CVaRHLabel.Text = 'CVaR H';
            app.EditField_2 = uieditfield(app.ResultsPanel, 'text');
            app.CVaRQLabel = uilabel(app.ResultsPanel);
            app.CVaRQLabel.Text = 'CVaR Q';
            app.EditField_3 = uieditfield(app.ResultsPanel, 'text');
            app.CVaRDLabel = uilabel(app.ResultsPanel);
            app.CVaRDLabel.Text = 'CVaR D';
            app.EditField_4 = uieditfield(app.ResultsPanel, 'text');
            app.meanHLabel_2 = uilabel(app.ResultsPanel);
            app.meanHLabel_2.Text = 'mean H';
            app.EditField_6 = uieditfield(app.ResultsPanel, 'text');
            app.meanQLabel_2 = uilabel(app.ResultsPanel);
            app.meanQLabel_2.Text = 'mean Q';
            app.EditField_7 = uieditfield(app.ResultsPanel, 'text');
            app.RadQLabel_2 = uilabel(app.ResultsPanel);
            app.RadQLabel_2.Text = 'Rad Q';
            app.EditField_8 = uieditfield(app.ResultsPanel, 'text');
            app.meanDLabel_2 = uilabel(app.ResultsPanel);
            app.meanDLabel_2.Text = 'mean D';
            app.EditField_9 = uieditfield(app.ResultsPanel, 'text');
            app.RadDLabel_2 = uilabel(app.ResultsPanel);
            app.RadDLabel_2.Text = 'Rad D';
            app.EditField_10 = uieditfield(app.ResultsPanel, 'text');
            app.LambdaLabel = uilabel(app.ResultsPanel);
            app.LambdaLabel.Text = 'Lambda';
            app.EditField_5 = uieditfield(app.ResultsPanel, 'text');
            app.RadHLabel_2 = uilabel(app.ResultsPanel);
            app.RadHLabel_2.Text = 'Rad H';
            app.ParetoDropDownLabel = uilabel(app.ResultsPanel);
            app.ParetoDropDownLabel.Text = 'Pareto #';
            app.ParetoDropDown = uidropdown(app.ResultsPanel);
            app.ParetoDropDown.Items = {};
            app.ParetoDropDown.ValueChangedFcn = createCallbackFcn(app, @ParetoDropDownValueChanged, true);
            app.ParetoDropDown.Value = {};
            app.EditField_11 = uieditfield(app.ResultsPanel, 'text');
            app.CLabel = uilabel(app.ResultsPanel);
            app.CLabel.Text = 'C';
            app.EditField_12 = uieditfield(app.ResultsPanel, 'text');
            app.aLabel = uilabel(app.ResultsPanel);
            app.aLabel.Text = 'a';
            app.UIAxes3 = uiaxes(app.ResultsPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes2 = uiaxes(app.ResultsPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RiskMethodsButtonGroup = uibuttongroup(app.WeirDesignUIFigure);
            app.RiskMethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RiskMethodsButtonGroupSelectionChanged, true);
            app.RiskMethodsButtonGroup.Title = 'Risk Methods';
            app.CVaRButton = uitogglebutton(app.RiskMethodsButtonGroup);
            app.CVaRButton.Text = 'CVaR';
            app.CVaRButton.Value = true;
            app.NINPButton = uitogglebutton(app.RiskMethodsButtonGroup);
            app.NINPButton.Text = 'NINP';
            app.CVaRNINPButton = uitogglebutton(app.RiskMethodsButtonGroup);
            app.CVaRNINPButton.Text = 'CVaR+NINP';
            app.ObjectiveFunctionsPanel = uipanel(app.RiskMethodsButtonGroup);
            app.ObjectiveFunctionsPanel.Title = 'Objective Functions';
            app.MinimizeConditionalValueatRiskforLabel = uilabel(app.ObjectiveFunctionsPanel);
            app.MinimizeConditionalValueatRiskforLabel.Text = 'Minimize: Conditional Value-at-Risk for:';
            app.HeadCVaRHLabel = uilabel(app.ObjectiveFunctionsPanel);
            app.HeadCVaRHLabel.Text = '1. Head (CVaR H)';
            app.OutflowCVaRQLabel = uilabel(app.ObjectiveFunctionsPanel);
            app.OutflowCVaRQLabel.Text = '2. Outflow (CVaR Q)';
            app.DeficitCVaRDLabel = uilabel(app.ObjectiveFunctionsPanel);
            app.DeficitCVaRDLabel.Text = '3. Deficit (CVaR D)';
            app.MinimizeLabel = uilabel(app.ObjectiveFunctionsPanel);
            app.MinimizeLabel.Text = 'Minimize:';
            app.LambdaLabel_3 = uilabel(app.ObjectiveFunctionsPanel);
            app.LambdaLabel_3.Text = '4. Lambda';
            app.CLabel_7 = uilabel(app.ObjectiveFunctionsPanel);
            app.CLabel_7.Text = '5. C';
            app.aLabel_3 = uilabel(app.ObjectiveFunctionsPanel);
            app.aLabel_3.Text = '6. a';
            app.CLabel_9 = uilabel(app.ObjectiveFunctionsPanel);
            app.CLabel_9.Text = '4. C';
            app.SLabel_2 = uilabel(app.ObjectiveFunctionsPanel);
            app.SLabel_2.Text = '5. S';
            app.CLabel_10 = uilabel(app.ObjectiveFunctionsPanel);
            app.CLabel_10.Text = '4. C';
            app.LLabel_2 = uilabel(app.ObjectiveFunctionsPanel);
            app.LLabel_2.Text = '5. L';
            app.CLabel_11 = uilabel(app.ObjectiveFunctionsPanel);
            app.CLabel_11.Text = '4. C';
            app.TETALabel_2 = uilabel(app.ObjectiveFunctionsPanel);
            app.TETALabel_2.Text = '5. TETA';
            app.MinimizeConditionalValueatRiskforLabel_2 = uilabel(app.ObjectiveFunctionsPanel);
            app.MinimizeConditionalValueatRiskforLabel_2.Text = 'Minimize: Conditional Value-at-Risk for:';
            app.HeadCVaRHLabel_2 = uilabel(app.ObjectiveFunctionsPanel);
            app.HeadCVaRHLabel_2.Text = '1. Head (CVaR H)';
            app.OutflowCVaRQLabel_2 = uilabel(app.ObjectiveFunctionsPanel);
            app.OutflowCVaRQLabel_2.Text = '2. Outflow (CVaR Q)';
            app.DeficitCVaRDLabel_2 = uilabel(app.ObjectiveFunctionsPanel);
            app.DeficitCVaRDLabel_2.Text = '3. Deficit (CVaR D)';
            app.MinimizeLabel_2 = uilabel(app.ObjectiveFunctionsPanel);
            app.MinimizeLabel_2.Text = 'Minimize:';
            app.RadiusofHeadRadHLabel = uilabel(app.ObjectiveFunctionsPanel);
            app.RadiusofHeadRadHLabel.Text = '1. Radius of Head (Rad H)';
            app.MinimizeLabel_3 = uilabel(app.ObjectiveFunctionsPanel);
            app.MinimizeLabel_3.Text = 'Minimize:';
            app.LambdaLabel_4 = uilabel(app.ObjectiveFunctionsPanel);
            app.LambdaLabel_4.Text = '7. Lambda';
            app.CLabel_8 = uilabel(app.ObjectiveFunctionsPanel);
            app.CLabel_8.Text = '8. C';
            app.aLabel_4 = uilabel(app.ObjectiveFunctionsPanel);
            app.aLabel_4.Text = '9. a';
            app.CLabel_12 = uilabel(app.ObjectiveFunctionsPanel);
            app.CLabel_12.Text = '7. C';
            app.SLabel_3 = uilabel(app.ObjectiveFunctionsPanel);
            app.SLabel_3.Text = '8. S';
            app.CLabel_13 = uilabel(app.ObjectiveFunctionsPanel);
            app.CLabel_13.Text = '7. C';
            app.CLabel_14 = uilabel(app.ObjectiveFunctionsPanel);
            app.CLabel_14.Text = '7. C';
            app.LLabel_3 = uilabel(app.ObjectiveFunctionsPanel);
            app.LLabel_3.Text = '8. L';
            app.TETALabel_3 = uilabel(app.ObjectiveFunctionsPanel);
            app.TETALabel_3.Text = '8. TETA';
            app.MinimizeLabel_4 = uilabel(app.RiskMethodsButtonGroup);
            app.MinimizeLabel_4.Text = 'Minimize:';
            app.RadiusofHeadRadHLabel_2 = uilabel(app.RiskMethodsButtonGroup);
            app.RadiusofHeadRadHLabel_2.Text = '1. Radius of Head (Rad H)';
            app.MeanofHmeanHLabel_2 = uilabel(app.RiskMethodsButtonGroup);
            app.MeanofHmeanHLabel_2.Text = '2. Mean of H (mean H)';
            app.RadiusofOutflowRadQLabel_2 = uilabel(app.RiskMethodsButtonGroup);
            app.RadiusofOutflowRadQLabel_2.Text = '3. Radius of Outflow (Rad Q)';
            app.MeanofOutflowmeanQLabel_2 = uilabel(app.RiskMethodsButtonGroup);
            app.MeanofOutflowmeanQLabel_2.Text = '4. Mean of Outflow (mean Q)';
            app.RadiusofDeficitRadDLabel_2 = uilabel(app.RiskMethodsButtonGroup);
            app.RadiusofDeficitRadDLabel_2.Text = '5. Radius of Deficit (Rad D)';
            app.MeanofDeficitmeanDLabel_2 = uilabel(app.RiskMethodsButtonGroup);
            app.MeanofDeficitmeanDLabel_2.Text = '6. Mean of Deficit (mean D)';
            app.MinimizeLabel_5 = uilabel(app.RiskMethodsButtonGroup);
            app.MinimizeLabel_5.Text = 'Minimize:';
            app.LambdaLabel_5 = uilabel(app.RiskMethodsButtonGroup);
            app.LambdaLabel_5.Text = '7. Lambda';
            app.CLabel_15 = uilabel(app.RiskMethodsButtonGroup);
            app.CLabel_15.Text = '8. C';
            app.aLabel_5 = uilabel(app.RiskMethodsButtonGroup);
            app.aLabel_5.Text = '9. a';
            app.CLabel_16 = uilabel(app.RiskMethodsButtonGroup);
            app.CLabel_16.Text = '7. C';
            app.SLabel_4 = uilabel(app.RiskMethodsButtonGroup);
            app.SLabel_4.Text = '8. S';
            app.LLabel_4 = uilabel(app.RiskMethodsButtonGroup);
            app.LLabel_4.Text = '8. L';
            app.TETALabel_4 = uilabel(app.RiskMethodsButtonGroup);
            app.TETALabel_4.Text = '8. TETA';
            app.CLabel_17 = uilabel(app.RiskMethodsButtonGroup);
            app.CLabel_17.Text = '7. C';
            app.CLabel_18 = uilabel(app.RiskMethodsButtonGroup);
            app.CLabel_18.Text = '7. C';
            app.MeanofHmeanHLabel = uilabel(app.WeirDesignUIFigure);
            app.MeanofHmeanHLabel.Text = '2. Mean of H (mean H)';
            app.RadiusofOutflowRadQLabel = uilabel(app.WeirDesignUIFigure);
            app.RadiusofOutflowRadQLabel.Text = '3. Radius of Outflow (Rad Q)';
            app.MeanofOutflowmeanQLabel = uilabel(app.WeirDesignUIFigure);
            app.MeanofOutflowmeanQLabel.Text = '4. Mean of Outflow (mean Q)';
            app.RadiusofDeficitRadDLabel = uilabel(app.WeirDesignUIFigure);
            app.RadiusofDeficitRadDLabel.Text = '5. Radius of Deficit (Rad D)';
            app.MeanofDeficitmeanDLabel = uilabel(app.WeirDesignUIFigure);
            app.MeanofDeficitmeanDLabel.Text = '6. Mean of Deficit (mean D)';
            % Show the figure after all components are created
            app.WeirDesignUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Weir_Designer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WeirDesignUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WeirDesignUIFigure)
        end
    end
end